classdef linear_conv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CalculateButton       matlab.ui.control.Button
        n2EndEditField        matlab.ui.control.EditField
        n2EditField_2Label    matlab.ui.control.Label
        n2StartEditField      matlab.ui.control.EditField
        n2Label               matlab.ui.control.Label
        n1EndEditField        matlab.ui.control.EditField
        n1EditField_2Label    matlab.ui.control.Label
        n1StartEditField      matlab.ui.control.EditField
        n1Label               matlab.ui.control.Label
        hExpressionEditField  matlab.ui.control.EditField
        hnLabel               matlab.ui.control.Label
        xExpressionEditField  matlab.ui.control.EditField
        xnLabel               matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes1               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            % 获取用户输入的表达式
            x_expr = app.xExpressionEditField.Value;
            h_expr = app.hExpressionEditField.Value;
            
            % 获取时间轴范围
            n1_start = str2double(app.n1StartEditField.Value);
            n1_end = str2double(app.n1EndEditField.Value);
            n2_start = str2double(app.n2StartEditField.Value);
            n2_end = str2double(app.n2EndEditField.Value);
            
            n1 = n1_start:n1_end;
            n2 = n2_start:n2_end;
            
            % 计算信号 x[n]
            n = n1; % 为了在表达式中使用
            x = eval(x_expr);
            
            % 计算信号 h[n]
            n = n2;
            h = eval(h_expr);
            
            % 计算线性卷积 y[n]
            y = conv(x, h);
            ny = (n1_start + n2_start):(n1_end + n2_end);
            
            % 绘制信号 x[n]
            stem(app.UIAxes1, n1, x, 'filled');
            title(app.UIAxes1, '信号 x[n]');
            xlabel(app.UIAxes1, 'n');
            ylabel(app.UIAxes1, 'x[n]');
            
            % 绘制信号 h[n]
            stem(app.UIAxes2, n2, h, 'filled');
            title(app.UIAxes2, '信号 h[n]');
            xlabel(app.UIAxes2, 'n');
            ylabel(app.UIAxes2, 'h[n]');
            
            % 绘制卷积结果 y[n]
            stem(app.UIAxes3, ny, y, 'filled');
            title(app.UIAxes3, '线性卷积 y[n] = x[n] * h[n]');
            xlabel(app.UIAxes3, 'n');
            ylabel(app.UIAxes3, 'y[n]');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'x[n]')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'y[n]')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'h[n]')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.xnLabel = uilabel(app.UIFigure);
            app.xnLabel.Text = '信号x[n]';
            app.xExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.hnLabel = uilabel(app.UIFigure);
            app.hnLabel.Text = '信号h[n]';
            app.hExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.n1Label = uilabel(app.UIFigure);
            app.n1Label.Text = 'n1起始';
            app.n1StartEditField = uieditfield(app.UIFigure, 'text');
            app.n1EditField_2Label = uilabel(app.UIFigure);
            app.n1EditField_2Label.Text = 'n1结束';
            app.n1EndEditField = uieditfield(app.UIFigure, 'text');
            app.n2Label = uilabel(app.UIFigure);
            app.n2Label.Text = 'n2起始';
            app.n2StartEditField = uieditfield(app.UIFigure, 'text');
            app.n2EditField_2Label = uilabel(app.UIFigure);
            app.n2EditField_2Label.Text = 'n2结束';
            app.n2EndEditField = uieditfield(app.UIFigure, 'text');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = '计算';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear_conv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end