classdef linear_conv_show < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button_pre            matlab.ui.control.StateButton
        Button_next           matlab.ui.control.StateButton
        nSlider               matlab.ui.control.Slider
        nSliderLabel          matlab.ui.control.Label
        n2EndEditField        matlab.ui.control.EditField
        n2EditField_2Label    matlab.ui.control.Label
        n2StartEditField      matlab.ui.control.EditField
        n2EditFieldLabel      matlab.ui.control.Label
        n1EndEditField        matlab.ui.control.EditField
        n1EditField_2Label    matlab.ui.control.Label
        n1StartEditField      matlab.ui.control.EditField
        n1EditFieldLabel      matlab.ui.control.Label
        hExpressionEditField  matlab.ui.control.EditField
        hnLabel               matlab.ui.control.Label
        xExpressionEditField  matlab.ui.control.EditField
        xnEditFieldLabel      matlab.ui.control.Label
        UIAxes_h              matlab.ui.control.UIAxes
        UIAxes_y              matlab.ui.control.UIAxes
        UIAxes_x              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentStep double = 0; % 当前步骤，初始值为 0
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function updateConvolutionProcess(app)

        persistent x_signal h_signal n1_range n2_range n2_flip h_flip h_shift n1_start n1_end n2_start n2_end; % 持久化变量
        % 第一步：绘制 x[n] 和 h[n]
        if app.currentStep == 1
            % 获取时间范围
            n1_start = str2double(app.n1StartEditField.Value);
            n1_end = str2double(app.n1EndEditField.Value);
            n2_start = str2double(app.n2StartEditField.Value);
            n2_end = str2double(app.n2EndEditField.Value);

            % 定义时间轴
            n1_range = n1_start:n1_end;
            n2_range = n2_start:n2_end;

            % 计算信号 x[n] 和 h[n]
            n = n1_range;
            x_signal = eval(app.xExpressionEditField.Value);
            n = n2_range;
            h_signal = eval(app.hExpressionEditField.Value);
            % 绘制 x[n] 和 h[n]
            cla(app.UIAxes_x);
            stem(app.UIAxes_x, n1_range, x_signal, 'b', 'filled');
            title(app.UIAxes_x, '信号 x[n]');
            xlabel(app.UIAxes_x, 'n');
            ylabel(app.UIAxes_x, 'x[n]');
            
            cla(app.UIAxes_h);
            stem(app.UIAxes_h, n2_range, h_signal, 'r', 'filled');
            title(app.UIAxes_h, '信号 h[n]');
            xlabel(app.UIAxes_h, 'n');
            ylabel(app.UIAxes_h, 'h[n]');
            
            % 清空卷积结果图表
            cla(app.UIAxes_y);
            title(app.UIAxes_y, '卷积过程');
            xlabel(app.UIAxes_y, 'n');
            ylabel(app.UIAxes_y, '结果');

        % 第二步：翻折 h[n]
        elseif app.currentStep == 2
            h_flip = fliplr(h_signal); % 翻折 h[n]
            n2_flip = -fliplr(n2_range); % 翻折后的时间轴
            cla(app.UIAxes_y);
            stem(app.UIAxes_y, n2_flip, h_flip, 'r', 'filled');
            title(app.UIAxes_y, '翻折后的 h[-k]');
            xlabel(app.UIAxes_y, 'n');
            ylabel(app.UIAxes_y, '幅值');

        % 第三步：移位 h[-k + n]（使用滑块控制移位量）
        elseif app.currentStep == 3
            shift = round(app.nSlider.Value); % 获取滑块的移位量
            h_shift = n2_flip + shift;
            cla(app.UIAxes_y);
            stem(app.UIAxes_y, h_shift, h_flip, 'r', 'filled');
            title(app.UIAxes_y, ['移位后的 h[-k + n], n = ', num2str(shift)]);
            xlabel(app.UIAxes_y, 'n');
            ylabel(app.UIAxes_y, '幅值');

        % 第四步：相乘并显示相乘结果
        elseif app.currentStep == 4
            shift = round(app.nSlider.Value); % 获取滑块的移位量
            h_shift = n2_flip + shift;
            [n_overlap, idx_x, idx_h] = intersect(n1_range, h_shift); % 找到重叠区域
            x_overlap = x_signal(idx_x); % 获取 x 的重叠部分
            h_overlap = h_flip(idx_h);   % 获取 h 的重叠部分
            product = x_overlap .* h_overlap; % 相乘结果
            cla(app.UIAxes_y);
            stem(app.UIAxes_y, n_overlap, product, 'g', 'filled');
            title(app.UIAxes_y, ['相乘结果，移位 n = ', num2str(shift)]);
            xlabel(app.UIAxes_y, 'n');
            ylabel(app.UIAxes_y, '幅值');

        % 第五步：显示最终卷积结果
        elseif app.currentStep == 5
            y_conv = conv(x_signal, h_signal); % 计算卷积
            ny = (n1_start + n2_start):(n1_end + n2_end); % 卷积的时间范围
            cla(app.UIAxes_y);
            stem(app.UIAxes_y, ny, y_conv, 'm', 'filled'); % 显示卷积结果
            title(app.UIAxes_y, '卷积结果 y[n]');
            xlabel(app.UIAxes_y, 'n');
            ylabel(app.UIAxes_y, 'y[n]');
        end
        end

        % Value changed function: nSlider
        function StepSliderValueChanged2(app, event)
            app.updateConvolutionProcess();
        end

        % Value changed function: Button_pre
        function Button_preValueChanged(app, event)
            if app.currentStep > 0
                app.currentStep = app.currentStep - 1;
                app.updateConvolutionProcess();
            end
        end

        % Value changed function: Button_next
        function ButtonValueChanged(app, event)
            if app.currentStep < 5
                app.currentStep = app.currentStep + 1;
                app.updateConvolutionProcess();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_x = uiaxes(app.UIFigure);
            title(app.UIAxes_x, 'x[n]')
            xlabel(app.UIAxes_x, 'X')
            ylabel(app.UIAxes_x, 'Y')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_y = uiaxes(app.UIFigure);
            title(app.UIAxes_y, 'y[n]')
            xlabel(app.UIAxes_y, 'X')
            ylabel(app.UIAxes_y, 'Y')
            zlabel(app.UIAxes_y, 'Z')
            app.UIAxes_h = uiaxes(app.UIFigure);
            title(app.UIAxes_h, 'h[n]')
            xlabel(app.UIAxes_h, 'X')
            ylabel(app.UIAxes_h, 'Y')
            zlabel(app.UIAxes_h, 'Z')
            app.xnEditFieldLabel = uilabel(app.UIFigure);
            app.xnEditFieldLabel.Text = 'x[n]';
            app.xExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.hnLabel = uilabel(app.UIFigure);
            app.hnLabel.Text = 'h[n]';
            app.hExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.n1EditFieldLabel = uilabel(app.UIFigure);
            app.n1EditFieldLabel.Text = 'n1开始';
            app.n1StartEditField = uieditfield(app.UIFigure, 'text');
            app.n1EditField_2Label = uilabel(app.UIFigure);
            app.n1EditField_2Label.Text = 'n1结束';
            app.n1EndEditField = uieditfield(app.UIFigure, 'text');
            app.n2EditFieldLabel = uilabel(app.UIFigure);
            app.n2EditFieldLabel.Text = 'n2开始';
            app.n2StartEditField = uieditfield(app.UIFigure, 'text');
            app.n2EditField_2Label = uilabel(app.UIFigure);
            app.n2EditField_2Label.Text = 'n2结束';
            app.n2EndEditField = uieditfield(app.UIFigure, 'text');
            app.nSliderLabel = uilabel(app.UIFigure);
            app.nSliderLabel.Text = '移位n';
            app.nSlider = uislider(app.UIFigure);
            app.nSlider.Limits = [-5 5];
            app.nSlider.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @StepSliderValueChanged2, true);
            app.nSlider.MinorTicks = [];
            app.Button_next = uibutton(app.UIFigure, 'state');
            app.Button_next.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button_next.Text = '下一步';
            app.Button_pre = uibutton(app.UIFigure, 'state');
            app.Button_pre.ValueChangedFcn = createCallbackFcn(app, @Button_preValueChanged, true);
            app.Button_pre.Text = '上一步';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear_conv_show

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @updateConvolutionProcess)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end