classdef contrast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NSlider               matlab.ui.control.Slider
        nLabel                matlab.ui.control.Label
        n2EndEditField        matlab.ui.control.EditField
        n2EditField_2Label    matlab.ui.control.Label
        n2StartEditField      matlab.ui.control.EditField
        n2EditFieldLabel      matlab.ui.control.Label
        n1EndEditField        matlab.ui.control.EditField
        n1EditField_2Label    matlab.ui.control.Label
        n1StartEditField      matlab.ui.control.EditField
        n1EditFieldLabel      matlab.ui.control.Label
        hExpressionEditField  matlab.ui.control.EditField
        hnLabel               matlab.ui.control.Label
        xExpressionEditField  matlab.ui.control.EditField
        xnEditFieldLabel      matlab.ui.control.Label
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes1               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function updateConvolutionProcess(app)
            persistent x h n1 n2; % 持久化变量
            
            % 获取 N 的值（圆周卷积长度）
            N = round(app.NSlider.Value);
            
            % 如果 N = 1，绘制线性卷积和圆周卷积
            if N == 1
                % 获取用户输入的表达式
                x_expr = app.xExpressionEditField.Value;
                h_expr = app.hExpressionEditField.Value;
            
                % 获取时间轴范围
                n1_start = str2double(app.n1StartEditField.Value);
                n1_end = str2double(app.n1EndEditField.Value);
                n2_start = str2double(app.n2StartEditField.Value);
                n2_end = str2double(app.n2EndEditField.Value);
                
                n1 = n1_start:n1_end;
                n2 = n2_start:n2_end;
                
                % 计算信号 x[n]
                n = n1; % 为了在表达式中使用
                x = eval(x_expr);
                
                % 计算信号 h[n]
                n = n2;
                h = eval(h_expr);
                
                % 计算线性卷积 y[n]
                y_linear = conv(x, h);
                ny = (n1_start + n2_start):(n1_end + n2_end);
                cla(app.UIAxes1);  % 清除先前的图形
                stem(app.UIAxes1, n1, x, 'b', 'filled');
                title(app.UIAxes1, '信号 x[n]');
                xlabel(app.UIAxes1, 'n');
                ylabel(app.UIAxes1, 'x[n]');
                
                cla(app.UIAxes2);  % 清除先前的图形
                stem(app.UIAxes2, n2, h, 'r', 'filled');
                title(app.UIAxes2, '信号 h[n]');
                xlabel(app.UIAxes2, 'n');
                ylabel(app.UIAxes2, 'h[n]');
                % 绘制线性卷积
                cla(app.UIAxes3);  % 清除之前的图形
                stem(app.UIAxes3, ny, y_linear, 'b', 'filled');
                title(app.UIAxes3, '线性卷积结果 y[n]');
                xlabel(app.UIAxes3, 'n');
                ylabel(app.UIAxes3, 'y[n]');
                
                % 对 x 和 h 进行补零以执行圆周卷积
                if length(x) > N
                    x_circ = x(1:N);  % 截断
                else
                    x_circ = [x zeros(1, N - length(x))];  % 补零
                end
        
                if length(h) > N
                    h_circ = h(1:N);  % 截断
                else
                    h_circ = [h zeros(1, N - length(h))];  % 补零
                end
                
                % 计算圆周卷积
                y_circ = cconv(x_circ, h_circ, N);
                ny_circ = 0:(N-1);
                
                % 绘制圆周卷积
                cla(app.UIAxes4);  % 清除之前的图形
                stem(app.UIAxes4, ny_circ, y_circ, 'r', 'filled');
                title(app.UIAxes4, '圆周卷积结果 y_c[n]');
                xlabel(app.UIAxes4, 'n');
                ylabel(app.UIAxes4, 'y_c[n]');
            
            % 如果 N > 1，仅绘制圆周卷积
            elseif N > 1
                % 对 x 和 h 进行补零以执行圆周卷积
                if length(x) > N
                    x_circ = x(1:N);  % 截断
                else
                    x_circ = [x zeros(1, N - length(x))];  % 补零
                end
        
                if length(h) > N
                    h_circ = h(1:N);  % 截断
                else
                    h_circ = [h zeros(1, N - length(h))];  % 补零
                end
                
                % 计算圆周卷积
                y_circ = cconv(x_circ, h_circ, N);
                ny_circ = 0:(N-1);
                
                % 绘制圆周卷积
                cla(app.UIAxes4);  % 清除之前的图形
                stem(app.UIAxes4, ny_circ, y_circ, 'r', 'filled');
                title(app.UIAxes4, ['圆周卷积结果 y_c[n] (N = ', num2str(N), ')']);
                xlabel(app.UIAxes4, 'n');
                ylabel(app.UIAxes4, 'y_c[n]');
            end
        end

        % Value changed function: NSlider
        function StepSliderValueChanged2(app, event)
            app.updateConvolutionProcess();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'x[n]')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'y[n]')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'h[n]')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Y[n]')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.xnEditFieldLabel = uilabel(app.UIFigure);
            app.xnEditFieldLabel.Text = 'x[n]';
            app.xExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.hnLabel = uilabel(app.UIFigure);
            app.hnLabel.Text = 'h[n]';
            app.hExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.n1EditFieldLabel = uilabel(app.UIFigure);
            app.n1EditFieldLabel.Text = 'n1开始';
            app.n1StartEditField = uieditfield(app.UIFigure, 'text');
            app.n1EditField_2Label = uilabel(app.UIFigure);
            app.n1EditField_2Label.Text = 'n1结束';
            app.n1EndEditField = uieditfield(app.UIFigure, 'text');
            app.n2EditFieldLabel = uilabel(app.UIFigure);
            app.n2EditFieldLabel.Text = 'n2开始';
            app.n2StartEditField = uieditfield(app.UIFigure, 'text');
            app.n2EditField_2Label = uilabel(app.UIFigure);
            app.n2EditField_2Label.Text = 'n2结束';
            app.n2EndEditField = uieditfield(app.UIFigure, 'text');
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = '圆周移位N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [0 20];
            app.NSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @StepSliderValueChanged2, true);
            app.NSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = contrast

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @updateConvolutionProcess)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end