classdef convlu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Switch                matlab.ui.control.Switch
        NEditField            matlab.ui.control.EditField
        NEditFieldLabel       matlab.ui.control.Label
        Button_pre            matlab.ui.control.StateButton
        Button_next           matlab.ui.control.StateButton
        nSlider               matlab.ui.control.Slider
        nLabel                matlab.ui.control.Label
        n2EndEditField        matlab.ui.control.EditField
        n2EditField_2Label    matlab.ui.control.Label
        n2StartEditField      matlab.ui.control.EditField
        n2EditFieldLabel      matlab.ui.control.Label
        n1EndEditField        matlab.ui.control.EditField
        n1EditField_2Label    matlab.ui.control.Label
        n1StartEditField      matlab.ui.control.EditField
        n1EditFieldLabel      matlab.ui.control.Label
        hExpressionEditField  matlab.ui.control.EditField
        hnLabel               matlab.ui.control.Label
        xExpressionEditField  matlab.ui.control.EditField
        xnEditFieldLabel      matlab.ui.control.Label
        UIAxes_h              matlab.ui.control.UIAxes
        UIAxes_y              matlab.ui.control.UIAxes
        UIAxes_x              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentStep double = 0; % 当前步骤，初始值为 0
        inTimeDomain logical = true; % 默认为时域操作
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function updateConvolutionProcess(app)

        persistent x_signal h_signal n1_range n2_range h_flip h_shift n1_start n1_end n2_start n2_end; % 持久化变量
      
        % 获取 N 的值（圆周长度），来自 NEditField
        N = str2double(app.NEditField.Value);
    
        % 初始状态，什么都不显示
        if app.currentStep == 0
            cla(app.UIAxes_x);
            cla(app.UIAxes_h);
            cla(app.UIAxes_y);
            title(app.UIAxes_x, '等待开始');
            title(app.UIAxes_h, '等待开始');
            title(app.UIAxes_y, '等待卷积过程');
    
        % 第一步：绘制原始信号
        elseif app.currentStep == 1
            % 获取时间范围和信号
            n1_start = str2double(app.n1StartEditField.Value);
            n1_end = str2double(app.n1EndEditField.Value);
            n2_start = str2double(app.n2StartEditField.Value);
            n2_end = str2double(app.n2EndEditField.Value);
            n1_range = n1_start:n1_end;
            n2_range = n2_start:n2_end;
    
            % 计算信号 x[n] 和 h[n]
            n = n1_range;
            x_signal = eval(app.xExpressionEditField.Value); % 计算 x[n] 的表达式
            n = n2_range;
            h_signal = eval(app.hExpressionEditField.Value); % 计算 h[n] 的表达式
    
            % 绘制原始信号
            cla(app.UIAxes_x);
            stem(app.UIAxes_x, n1_range, x_signal, 'b', 'filled');
            title(app.UIAxes_x, '原始信号 x[n]');
            xlabel(app.UIAxes_x, 'n');
            ylabel(app.UIAxes_x, 'x[n]');
    
            cla(app.UIAxes_h);
            stem(app.UIAxes_h, n2_range, h_signal, 'r', 'filled');
            title(app.UIAxes_h, '原始信号 h[n]');
            xlabel(app.UIAxes_h, 'n');
            ylabel(app.UIAxes_h, 'h[n]');
    
        % 第二步：时域卷积 - 补零/截断+翻褶 或 频域卷积 - 计算 DFT
        elseif app.currentStep == 2
            if app.inTimeDomain
                % 时域卷积 - 先补零或截断，再翻褶
                % 对 x_signal 和 h_signal 进行补零或截断到长度 N
                if length(x_signal) > N
                    x_signal = x_signal(1:N);  % 截断 x_signal 到长度 N
                else
                    x_signal = [x_signal zeros(1, N - length(x_signal))];  % 补零
                end
    
                if length(h_signal) > N
                    h_signal = h_signal(1:N);  % 截断 h_signal 到长度 N
                else
                    h_signal = [h_signal zeros(1, N - length(h_signal))];  % 补零
                end
    
                % 翻褶 h[n]
                h_flip = fliplr(h_signal);
                
                % 绘制补零/截断后的 x[n] 和 h[n]
                cla(app.UIAxes_x);
                stem(app.UIAxes_x, 0:N-1, x_signal, 'b', 'filled');
                title(app.UIAxes_x, '补零/截断后的 x[n]');
                xlabel(app.UIAxes_x, 'n');
                ylabel(app.UIAxes_x, 'x[n]');
    
                cla(app.UIAxes_h);
                stem(app.UIAxes_h, 0:N-1, h_flip, 'r', 'filled');
                title(app.UIAxes_h, '补零/截断并翻褶后的 h[n]');
                xlabel(app.UIAxes_h, 'n');
                ylabel(app.UIAxes_h, 'h[n]');
            else
                % 频域卷积 - 计算 DFT
                X = fft(x_signal, N);
                H = fft(h_signal, N);
    
                % 绘制 DFT 结果
                cla(app.UIAxes_x);
                stem(app.UIAxes_x, 0:length(X)-1, abs(X), 'b', 'filled');  % 显示频域的幅度
                title(app.UIAxes_x, 'DFT of x[n]');
                xlabel(app.UIAxes_x, 'k');
                ylabel(app.UIAxes_x, '|X[k]|');
    
                cla(app.UIAxes_h);
                stem(app.UIAxes_h, 0:length(H)-1, abs(H), 'r', 'filled');  % 显示频域的幅度
                title(app.UIAxes_h, 'DFT of h[n]');
                xlabel(app.UIAxes_h, 'k');
                ylabel(app.UIAxes_h, '|H[k]|');
            end
    
        % 第三步：时域卷积 - 圆周移位 或 频域卷积 - 点乘
        elseif app.currentStep == 3
            if app.inTimeDomain
                % 时域卷积 - 圆周移位
                shift = round(app.nSlider.Value);
                h_shift = circshift(h_flip, shift);
    
                % 绘制移位后的 h[n]
                cla(app.UIAxes_y);
                stem(app.UIAxes_y, 0:length(h_shift)-1, h_shift, 'r', 'filled');
                title(app.UIAxes_y, ['圆周移位后的 h[n], 移位 n = ', num2str(shift)]);
                xlabel(app.UIAxes_y, 'n');
                ylabel(app.UIAxes_y, 'h[n]');
            else
                % 频域卷积 - 点乘
                X = fft(x_signal, N);
                H = fft(h_signal, N);
                Y_freq = X .* H;
    
                % 绘制频域中的点乘结果
                cla(app.UIAxes_y);
                stem(app.UIAxes_y, 0:length(Y_freq)-1, abs(Y_freq), 'm', 'filled');  % 显示频域乘积的幅度
                title(app.UIAxes_y, '频域点乘结果 |Y[k]|');
                xlabel(app.UIAxes_y, 'k');
                ylabel(app.UIAxes_y, '|Y[k]|');
            end
    
        % 第四步：时域卷积 - 相乘 或 频域卷积 - IFFT
        elseif app.currentStep == 4
            if app.inTimeDomain
                % 时域卷积 - 相乘
                shift = round(app.nSlider.Value);
                h_shift = circshift(h_flip, shift);
    
                % 检查 x_signal 和 h_shift 的长度是否相等
                if length(x_signal) == length(h_shift)
                    product = x_signal .* h_shift;
                else
                    error('x_signal 和 h_shift 的长度不一致，无法相乘');
                end
    
                % 绘制相乘结果
                cla(app.UIAxes_y);
                stem(app.UIAxes_y, 0:length(product)-1, product, 'g', 'filled');
                title(app.UIAxes_y, 'x[n] 和 h[n] 的相乘结果');
                xlabel(app.UIAxes_y, 'n');
                ylabel(app.UIAxes_y, '乘积');
            else
                % 频域卷积 - 计算 IFFT
                X = fft(x_signal, N);
                H = fft(h_signal, N);
                Y_freq = X .* H;
                y_circular = ifft(Y_freq, N);
    
                % 绘制 IFFT 结果
                cla(app.UIAxes_y);
                stem(app.UIAxes_y, 0:length(y_circular)-1, y_circular, 'm', 'filled');
                title(app.UIAxes_y, '圆周卷积结果（频域转换回时域）');
                xlabel(app.UIAxes_y, 'n');
                ylabel(app.UIAxes_y, 'y[n]');
            end
        % 第五步：显示卷积结果
        elseif app.currentStep == 5
            if app.inTimeDomain
                % 时域卷积
                y_circular = cconv(x_signal, h_signal, N);
    
                cla(app.UIAxes_y);
                stem(app.UIAxes_y, 0:length(y_circular)-1, y_circular, 'm', 'filled');
                title(app.UIAxes_y, '圆周卷积结果（时域）');
                xlabel(app.UIAxes_y, 'n');
                ylabel(app.UIAxes_y, 'y[n]');
            else
                % 频域卷积
                X = fft(x_signal, N);
                H = fft(h_signal, N);
                Y_freq = X .* H;
                y_circular = ifft(Y_freq, N);
    
                cla(app.UIAxes_y);
                stem(app.UIAxes_y, 0:length(y_circular)-1, y_circular, 'm', 'filled');
                title(app.UIAxes_y, '圆周卷积结果（频域）');
                xlabel(app.UIAxes_y, 'n');
                ylabel(app.UIAxes_y, 'y[n]');
            end
        end

        end

        % Value changed function: nSlider
        function StepSliderValueChanged2(app, event)
            app.updateConvolutionProcess();
        end

        % Value changed function: Button_pre
        function Button_preValueChanged(app, event)
            if app.currentStep > 0
                app.currentStep = app.currentStep - 1;
                app.updateConvolutionProcess();
            end
        end

        % Value changed function: Button_next
        function ButtonValueChanged(app, event)
            if app.currentStep < 5
                app.currentStep = app.currentStep + 1;
                app.updateConvolutionProcess();
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;    % 根据 Switch 的值更新 inTimeDomain 变量
            if strcmp(value, '时域')  % 如果开关设置为时域
                app.inTimeDomain = true;  % 设置为时域
            elseif strcmp(value, '频域')  % 如果开关设置为频域
                app.inTimeDomain = false; % 设置为频域
            end
            app.updateConvolutionProcess(); % 切换后更新卷积过程
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_x = uiaxes(app.UIFigure);
            title(app.UIAxes_x, 'x[n]')
            xlabel(app.UIAxes_x, 'X')
            ylabel(app.UIAxes_x, 'Y')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_y = uiaxes(app.UIFigure);
            title(app.UIAxes_y, 'y[n]')
            xlabel(app.UIAxes_y, 'X')
            ylabel(app.UIAxes_y, 'Y')
            zlabel(app.UIAxes_y, 'Z')
            app.UIAxes_h = uiaxes(app.UIFigure);
            title(app.UIAxes_h, 'h[n]')
            xlabel(app.UIAxes_h, 'X')
            ylabel(app.UIAxes_h, 'Y')
            zlabel(app.UIAxes_h, 'Z')
            app.xnEditFieldLabel = uilabel(app.UIFigure);
            app.xnEditFieldLabel.Text = 'x[n]';
            app.xExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.hnLabel = uilabel(app.UIFigure);
            app.hnLabel.Text = 'h[n]';
            app.hExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.n1EditFieldLabel = uilabel(app.UIFigure);
            app.n1EditFieldLabel.Text = 'n1开始';
            app.n1StartEditField = uieditfield(app.UIFigure, 'text');
            app.n1EditField_2Label = uilabel(app.UIFigure);
            app.n1EditField_2Label.Text = 'n1结束';
            app.n1EndEditField = uieditfield(app.UIFigure, 'text');
            app.n2EditFieldLabel = uilabel(app.UIFigure);
            app.n2EditFieldLabel.Text = 'n2开始';
            app.n2StartEditField = uieditfield(app.UIFigure, 'text');
            app.n2EditField_2Label = uilabel(app.UIFigure);
            app.n2EditField_2Label.Text = 'n2结束';
            app.n2EndEditField = uieditfield(app.UIFigure, 'text');
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = '圆周移位n';
            app.nSlider = uislider(app.UIFigure);
            app.nSlider.Limits = [-5 5];
            app.nSlider.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @StepSliderValueChanged2, true);
            app.nSlider.MinorTicks = [];
            app.nSlider.Value = 1;
            app.Button_next = uibutton(app.UIFigure, 'state');
            app.Button_next.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button_next.Text = '下一步';
            app.Button_pre = uibutton(app.UIFigure, 'state');
            app.Button_pre.ValueChangedFcn = createCallbackFcn(app, @Button_preValueChanged, true);
            app.Button_pre.Text = '上一步';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = '长度N';
            app.NEditField = uieditfield(app.UIFigure, 'text');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'时域', '频域'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '时域';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convlu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @updateConvolutionProcess)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end