classdef filtered < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        playButton3         matlab.ui.control.Button
        playButton2         matlab.ui.control.Button
        PlayButton          matlab.ui.control.Button
        dBEditField_stop    matlab.ui.control.EditField
        dBEditField_2Label  matlab.ui.control.Label
        dBEditField_pass    matlab.ui.control.EditField
        dBEditFieldLabel    matlab.ui.control.Label
        HzEditField_stop    matlab.ui.control.EditField
        HzEditField_2Label  matlab.ui.control.Label
        HzEditField_pass    matlab.ui.control.EditField
        HzEditFieldLabel    matlab.ui.control.Label
        DisplaySwitch       matlab.ui.control.Switch
        SNROutputLabel      matlab.ui.control.Label
        ApplyFilterButton   matlab.ui.control.Button
        FilterTypeDropDown  matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        AddNoiseButton      matlab.ui.control.Button
        LoadFileButton      matlab.ui.control.Button
        NoisyAxes           matlab.ui.control.UIAxes
        FilteredAxes        matlab.ui.control.UIAxes
        OriginalAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        original_audio       % 原始音频信号
        fs                   % 采样频率
        noisy_audio          % 加噪音频信号
        filtered_audio       % 滤波后的音频信号
        snr_before           % 滤波前的信噪比（SNR）
        snr_after            % 滤波后的信噪比（SNR）

    end
    
    methods (Access = private)
        % Helper function to plot time or frequency domain based on switch state
        function plotSignalOrSpectrum(app, axesHandle, signal, titleText)
            switch app.DisplaySwitch.Value
                case '时域'
                    plot(axesHandle, signal);
                    title(axesHandle, [titleText ' - 时域']);
                    xlabel(axesHandle, '时间 (s)');
                    ylabel(axesHandle, '幅度');
                case '频域'
                    % 计算频谱
                    N = length(signal);
                    f = (0:N-1) * (app.fs / N);
                    Y = abs(fft(signal));
                    plot(axesHandle, f, Y);
                    title(axesHandle, [titleText ' - 频域']);
                    xlabel(axesHandle, '频率 (Hz)');
                    ylabel(axesHandle, '幅度');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile('*.wav', '选择音频文件');
            if isequal(file, 0)
                return; % 如果未选择文件则返回
            end
            [app.original_audio, app.fs] = audioread(fullfile(path, file));
            
            % 显示原始音频信号
            plot(app.OriginalAxes, app.original_audio);
            title(app.OriginalAxes, '原始音频 - 时域');
            xlabel(app.OriginalAxes, '时间 (s)');
            ylabel(app.OriginalAxes, '幅度');
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            app.noisy_audio = app.original_audio + 0.03 * randn(size(app.original_audio));  % 添加高斯噪声
            app.snr_before = snr(app.original_audio, app.noisy_audio - app.original_audio); % 计算SNR
            
            % 显示加噪音频信号
            plot(app.NoisyAxes, app.noisy_audio);
            title(app.NoisyAxes, '加噪音频 - 时域');
            xlabel(app.NoisyAxes, '时间 (s)');
            ylabel(app.NoisyAxes, '幅度');
            
            % 更新SNR显示
            app.SNROutputLabel.Text = sprintf('滤波前 SNR: %.2f dB', app.snr_before);
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            app.noisy_audio = app.noisy_audio(:);  % 转换为列向量
            app.original_audio = app.original_audio(:);  % 转换为列向量
            
            % 从文本框读取参数
            passband_attenuation = str2double(app.dBEditField_pass.Value);  % 通带最小衰减 (dB)
            stopband_attenuation = str2double(app.dBEditField_stop.Value);  % 阻带最小衰减 (dB)
            passband_cutoff = str2double(app.HzEditField_pass.Value);       % 通带截止频率 (Hz)
            stopband_cutoff = str2double(app.HzEditField_stop.Value);       % 阻带截止频率 (Hz)
            
            % 计算归一化截止频率
            norm_cutoff = passband_cutoff / (app.fs / 2);
            
            % 获取滤波器类型选择
            filter_type = app.FilterTypeDropDown.Value;
            
            if strcmp(filter_type, 'FIR滤波器')
                % 根据阻带衰减选择最简单的窗函数
                if stopband_attenuation <= 21
                    window_type = 'rectwin';
                    transition_band = 1.8 * pi;
                elseif stopband_attenuation <= 44
                    window_type = 'hann';
                    transition_band = 6.2 * pi;
                elseif stopband_attenuation <= 53
                    window_type = 'hamming';
                    transition_band = 6.6 * pi;
                else
                    window_type = 'blackman';
                    transition_band = 11 * pi;
                end
            
                % 计算滤波器阶数
                delta_f = (stopband_cutoff - passband_cutoff) / app.fs; % 归一化过渡带宽
                order = ceil(transition_band / delta_f); % 计算阶数
                if mod(order, 2) == 0
                    order = order + 1; % 确保阶数为奇数
                end
            
                % 理想低通滤波器的冲激响应
                n = 0:order;
                center = floor(order / 2);  
                h_low = (sin(2 * pi * passband_cutoff * (n - center) / app.fs) ./ (pi * (n - center)));
                h_low(center + 1) = 2 * passband_cutoff / app.fs; % 修正中心点，避免除以0
            
                % 应用选定的窗函数
                switch window_type
                    case 'rectwin'
                        window = rectwin(order + 1)';
                    case 'hann'
                        window = hanning(order + 1)';
                    case 'hamming'
                        window = hamming(order + 1)';
                    case 'blackman'
                        window = blackman(order + 1)';
                end
                h_low_windowed = h_low .* window;
            
                % 对加噪信号进行滤波
                app.filtered_audio = filter(h_low_windowed, 1, app.noisy_audio);
            
            elseif strcmp(filter_type, 'IIR滤波器')
                % 使用 `buttord` 计算IIR滤波器的阶数和截止频率
                [iir_order, wn] = buttord(passband_cutoff/(app.fs/2), stopband_cutoff/(app.fs/2), passband_attenuation, stopband_attenuation);
            
                % 使用计算出的阶数和截止频率设计 Butterworth 低通滤波器
                [b, a] = butter(iir_order, wn);  % 使用计算的阶数和归一化截止频率
                app.filtered_audio = filter(b, a, app.noisy_audio);
            
            elseif strcmp(filter_type, 'FIR')
                % 使用 MATLAB 自带的 fir1 函数设计 FIR 滤波器
                fir_order = 100; % FIR 滤波器阶数，可以根据需求调整
                b = fir1(fir_order, norm_cutoff, 'low');  % 低通 FIR 滤波器
                app.filtered_audio = filter(b, 1, app.noisy_audio);
            
            elseif strcmp(filter_type, 'IIR')
                % 使用 MATLAB 自带的 designfilt 函数设计 IIR 滤波器
                iir_order = 8;  % IIR 滤波器阶数，可以根据需求调整
                iir_filter = designfilt('lowpassiir', 'FilterOrder', iir_order, ...
                    'PassbandFrequency', passband_cutoff, 'PassbandRipple', passband_attenuation, ...
                    'StopbandAttenuation', stopband_attenuation, 'SampleRate', app.fs);
                app.filtered_audio = filter(iir_filter, app.noisy_audio);
            elseif strcmp(filter_type,'谱减法')
                % 谱减法降噪
                noisy_audio_fft =fft(app.noisy_audio);
                noise_estimate = mean(abs(noisy_audio_fft)); % 简单估计噪声频谱
                noise_subtracted_fft = max(abs(noisy_audio_fft)- noise_estimate,8);%谱减
                phase = angle(noisy_audio_fft); % 保留相位信息
                noise_subtracted_signal = noise_subtracted_fft .*exp(1i * phase);% 还原复数形式
                app.filtered_audio = real(ifft(noise_subtracted_signal));%还原时域信号
            elseif strcmp(filter_type, '卡尔曼滤波')
                % 卡尔曼滤波降噪
                Q = 0.041;  % 过程噪声
                R = 0.06;  % 测量噪声
                N = length(app.noisy_audio);  % 确保使用原始信号长度
                x_est = zeros(N, 1);  % 初始化滤波后的信号数组
                P = 1;  % 初始误差协方差
                x_est(1) = app.noisy_audio(1);  % 使用第一个输入值初始化
                
                for i = 2:N
                    % 预测步骤
                    x_pred = x_est(i - 1);
                    P_pred = P + Q;
                    
                    % 更新步骤
                    K = P_pred / (P_pred + R);  % 计算卡尔曼增益
                    x_est(i) = x_pred + K * (app.noisy_audio(i) - x_pred);  % 更新估计值
                    P = (1 - K) * P_pred;  %更新误差协方差
                end
                
                % 确保输出长度与输入相同
                app.filtered_audio = x_est;
            elseif strcmp(filter_type, '优化谱减法')
                % 优化谱减法降噪
            
                % 参数设置
                apriori_SNR = 1;  % 选择 0 为后验 SNR 估计，1 为先验 SNR 估计
                alpha = 0.05;  % 仅在 apriori_SNR=1 时使用
                beta1 = 0.5;
                beta2 = 1;
                lambda = 3;
            
                % STFT 参数
                NFFT = 1024;
                window_length = round(0.031 * app.fs); 
                window = hamming(window_length);
                window = window(:);
                overlap = floor(0.45 * window_length);  % 重叠样本数
            
                % 噪声学习的时间区间
                t_min = 2.5;   % 用于噪声学习的最小时间（秒）
                t_max = 3.6;  % 用于噪声学习的最大时间（秒）
            
                % 计算短时傅里叶变换 (STFT)
                [S, F, T] = spectrogram(app.noisy_audio + 1i * eps, window, window_length - overlap, NFFT, app.fs);
                [Nf, Nw] = size(S);
            
                % 提取噪声频谱
                t_index = find(T > t_min & T < t_max);
                absS_vuvuzela = abs(S(:, t_index)).^2;
                vuvuzela_spectrum = mean(absS_vuvuzela, 2);  % 平均频谱
                vuvuzela_specgram = repmat(vuvuzela_spectrum, 1, Nw);
            
                % 估计 SNR
                absS = abs(S).^2;
                SNR_est = max((absS ./ vuvuzela_specgram) - 1, 0);  % 后验 SNR
                if apriori_SNR == 1
                    SNR_est = filter((1 - alpha), [1 -alpha], SNR_est);  % 先验 SNR
                end    
            
                % 计算衰减图
                an_lk = max((1 - lambda * ((1 ./ (SNR_est + 1)).^beta1)).^beta2, 0);
                STFT = an_lk .* S;
            
                % 逆 STFT，使用重叠相加法 (OLA)
                ind = mod((1:window_length) - 1, Nf) + 1;
                output_signal = zeros((Nw - 1) * overlap + window_length, 1);
            
                for indice = 1:Nw  % 重叠相加
                    left_index = (indice - 1) * overlap;
                    index = left_index + (1:window_length);
                    temp_ifft = real(ifft(STFT(:, indice), NFFT));
                    output_signal(index) = output_signal(index) + temp_ifft(ind) .* window;
                end
            
                % 将优化谱减法的结果保存为 app.filtered_audio
                app.filtered_audio = output_signal;
            elseif strcmp(filter_type, '神经网络滤波')
                % 简单的神经网络降噪（假设网络已加载）
                frame_length = 1024;  % 假设输入网络的帧长度为1024
                num_frames = floor(length(app.noisy_audio) / frame_length);
                app.filtered_audio = zeros(size(app.noisy_audio));
                
                for i = 1:num_frames
                    frame = app.noisy_audio((i-1)*frame_length+1:i*frame_length);
                    denoised_frame = predict(app.denoising_net, frame);  % 使用神经网络预测
                    app.filtered_audio((i-1)*frame_length+1:i*frame_length) = denoised_frame;
                end
            end
            
            % 确保 filtered_audio 和 original_audio 截断到相同长度
            min_length = min(length(app.filtered_audio), length(app.original_audio));
            filtered_audio_adjusted = app.filtered_audio(1:min_length);
            original_audio_adjusted = app.original_audio(1:min_length);
            
            % 计算滤波前的 SNR
            signal_power = rms(original_audio_adjusted).^2;  % 原始信号功率
            noise_power_before = rms(app.noisy_audio(1:min_length) - original_audio_adjusted).^2;  % 滤波前噪声功率
            app.snr_before = 10 * log10(signal_power / noise_power_before);
            
            % 计算滤波后的 SNR
            filtered_signal_power = rms(filtered_audio_adjusted).^2;  % 滤波后信号功率
            filtered_noise_power = rms(filtered_audio_adjusted - original_audio_adjusted).^2;  % 滤波后残余噪声功率
            app.snr_after = 10 * log10(filtered_signal_power / filtered_noise_power);
            
            % 显示滤波后的音频信号
            plot(app.FilteredAxes, app.filtered_audio);
            title(app.FilteredAxes, '滤波后音频 - 时域');
            xlabel(app.FilteredAxes, '时间 (s)');
            ylabel(app.FilteredAxes, '幅度');
            
            % 更新SNR显示
            app.SNROutputLabel.Text = sprintf('滤波前 SNR: %.2f dB\n滤波后 SNR: %.2f dB', app.snr_before, app.snr_after);
        end

        % Value changed function: DisplaySwitch
        function DisplaySwitchValueChanged(app, event)
            value = app.DisplaySwitch.Value;
              % 切换显示模式（时域或频域）
            if ~isempty(app.original_audio)
                plotSignalOrSpectrum(app, app.OriginalAxes, app.original_audio, '原始音频');
            end
            if ~isempty(app.noisy_audio)
                plotSignalOrSpectrum(app, app.NoisyAxes, app.noisy_audio, '加噪音频');
            end
            if ~isempty(app.filtered_audio)
                plotSignalOrSpectrum(app, app.FilteredAxes, app.filtered_audio, '滤波后音频');
            end
        end

        % Button pushed function: playButton3
        function playButton3Pushed(app, event)
            if ~isempty(app.filtered_audio)
                sound(app.filtered_audio, app.fs);
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if ~isempty(app.original_audio)
                sound(app.original_audio, app.fs);
            end 
        end

        % Button pushed function: playButton2
        function playButton2Pushed(app, event)
            if ~isempty(app.noisy_audio)
                sound(app.noisy_audio, app.fs);
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalAxes = uiaxes(app.UIFigure);
            title(app.OriginalAxes, 'Title')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.FilteredAxes = uiaxes(app.UIFigure);
            title(app.FilteredAxes, 'Title')
            xlabel(app.FilteredAxes, 'X')
            ylabel(app.FilteredAxes, 'Y')
            zlabel(app.FilteredAxes, 'Z')
            app.NoisyAxes = uiaxes(app.UIFigure);
            title(app.NoisyAxes, 'Title')
            xlabel(app.NoisyAxes, 'X')
            ylabel(app.NoisyAxes, 'Y')
            zlabel(app.NoisyAxes, 'Z')
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = '选择文件';
            app.AddNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = '添加噪声';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '滤波器选择';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'IIR', 'IIR滤波器', 'FIR', 'FIR滤波器', '谱减法', '优化谱减法', '卡尔曼滤波', '神经网络'};
            app.FilterTypeDropDown.Value = 'IIR';
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = '滤波';
            app.SNROutputLabel = uilabel(app.UIFigure);
            app.SNROutputLabel.Text = 'SNR:';
            app.DisplaySwitch = uiswitch(app.UIFigure, 'slider');
            app.DisplaySwitch.Items = {'时域', '频域'};
            app.DisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @DisplaySwitchValueChanged, true);
            app.DisplaySwitch.Value = '时域';
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = '通带截止频率（Hz）';
            app.HzEditField_pass = uieditfield(app.UIFigure, 'text');
            app.HzEditField_2Label = uilabel(app.UIFigure);
            app.HzEditField_2Label.Text = {'阻带截止频率（Hz）'; ''};
            app.HzEditField_stop = uieditfield(app.UIFigure, 'text');
            app.dBEditFieldLabel = uilabel(app.UIFigure);
            app.dBEditFieldLabel.Text = '通带最大衰减（dB）';
            app.dBEditField_pass = uieditfield(app.UIFigure, 'text');
            app.dBEditField_2Label = uilabel(app.UIFigure);
            app.dBEditField_2Label.Text = '阻带最小衰减（dB）';
            app.dBEditField_stop = uieditfield(app.UIFigure, 'text');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = '播放原声';
            app.playButton2 = uibutton(app.UIFigure, 'push');
            app.playButton2.ButtonPushedFcn = createCallbackFcn(app, @playButton2Pushed, true);
            app.playButton2.Text = '播放加噪';
            app.playButton3 = uibutton(app.UIFigure, 'push');
            app.playButton3.ButtonPushedFcn = createCallbackFcn(app, @playButton3Pushed, true);
            app.playButton3.Text = '播放滤波';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtered

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end